classdef InverseKinematicsTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SphericalParallelManipulatorUIFigure  matlab.ui.Figure
        OpenSerialButton            matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        CloseSerialButton           matlab.ui.control.Button
        RunSPMButton                matlab.ui.control.Button
        inxLabel                    matlab.ui.control.Label
        inxSlider                   matlab.ui.control.Slider
        inySliderLabel              matlab.ui.control.Label
        inySlider                   matlab.ui.control.Slider
        inzSliderLabel              matlab.ui.control.Label
        inzSlider                   matlab.ui.control.Slider
        Image2                      matlab.ui.control.Image
        TextArea                    matlab.ui.control.TextArea
        TextArea_2                  matlab.ui.control.TextArea
        MotorDegreesTextAreaLabel   matlab.ui.control.Label
        MotorDegreesTextArea        matlab.ui.control.TextArea
        TorqueTransmissionTextAreaLabel  matlab.ui.control.Label
        TorqueTransmissionTextArea  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        SPM = serial('COM12','BaudRate',115200); % Description
        out = "0,0,0";
        Torque = "1.5,1.3,3";
    end
    
    methods (Access = private)
        
        function results = Move(app, in)
            [thetaMotor,~] = InverseKinematics(in);
            M = TorqueTransmission([1 1 1], in);
            app.out = string(round(thetaMotor(1),1))+','+string(round(thetaMotor(2),1))+','+string(round(thetaMotor(3),1));
            app.Torque = string(round(M(1),1))+','+string(round(M(2),1))+','+string(round(M(3),1));
            app.TorqueTransmissionTextArea.Value = app.Torque;
            degrees = string(-round(thetaMotor(1),1))+','+string(-round(thetaMotor(2),1))+','+string(-round(thetaMotor(3),1));
            app.MotorDegreesTextArea.Value = degrees;
            if thetaMotor(1)<-51||thetaMotor(2)<-56||thetaMotor(3)>51||thetaMotor(1)>69||thetaMotor(2)>64||thetaMotor(3)<-59
                msgbox("Can't Go to this position due to motor range issues!", "Warning");
            else
                fprintf(app.SPM,app.out);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MotorDegreesTextArea.Value = app.out;
            app.TorqueTransmissionTextArea.Value = app.Torque;
            fopen(app.SPM);
            pause(2);
        end

        % Close request function: 
        % SphericalParallelManipulatorUIFigure
        function SphericalParallelManipulatorUIFigureCloseRequest(app, event)
            fclose(app.SPM);
            delete(app)
        end

        % Button pushed function: OpenSerialButton
        function OpenSerialButtonPushed(app, event)
            fopen(app.SPM);
            pause(2);
        end

        % Button pushed function: CloseSerialButton
        function CloseSerialButtonPushed(app, event)
            fclose(app.SPM);
        end

        % Button pushed function: RunSPMButton
        function RunSPMButtonPushed(app, event)
            in = [0 0 -30;...
                  0 0 30;...
                  0 0 0;...
                  0 -30 0;...
                  0 30  0;...
                  0 0   0;...
                  -30 0 0;...
                  20  0 0;...
                  0   0 0;...
                  0   0  -30;...
                  0   30 -30;...
                  20  30 -30;...
                  20  30 0;...
                  0   30 0;...
                  0   0  0]*pi/180;
            for i = 1:length(in)
                Move(app, in(i,:));
                pause(1);
            end
        end

        % Value changing function: inxSlider, inySlider, inzSlider
        function inxSliderValueChanging(app, event)
            value = event.Source.Value;
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            event.Source.Value = event.Source.MinorTicks(minIdx);
        end

        % Value changed function: inxSlider, inySlider, inzSlider
        function inxSliderValueChanged(app, event)
            inxSliderValueChanging(app, event);
            in = [app.inxSlider.Value*pi/180 app.inySlider.Value*pi/180 app.inzSlider.Value*pi/180];
            Move(app, in);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SphericalParallelManipulatorUIFigure = uifigure('Visible', 'off');
            app.SphericalParallelManipulatorUIFigure.Name = 'Spherical Parallel Manipulator';
            app.SphericalParallelManipulatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @SphericalParallelManipulatorUIFigureCloseRequest, true);
            app.OpenSerialButton = uibutton(app.SphericalParallelManipulatorUIFigure, 'push');
            app.OpenSerialButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSerialButtonPushed, true);
            app.OpenSerialButton.Text = 'Open Serial';
            app.Image = uiimage(app.SphericalParallelManipulatorUIFigure);
            app.Image.ImageSource = 'logo.png';
            app.CloseSerialButton = uibutton(app.SphericalParallelManipulatorUIFigure, 'push');
            app.CloseSerialButton.ButtonPushedFcn = createCallbackFcn(app, @CloseSerialButtonPushed, true);
            app.CloseSerialButton.Text = 'Close Serial';
            app.RunSPMButton = uibutton(app.SphericalParallelManipulatorUIFigure, 'push');
            app.RunSPMButton.ButtonPushedFcn = createCallbackFcn(app, @RunSPMButtonPushed, true);
            app.RunSPMButton.Text = 'Run SPM';
            app.inxLabel = uilabel(app.SphericalParallelManipulatorUIFigure);
            app.inxLabel.Text = 'inx';
            app.inxSlider = uislider(app.SphericalParallelManipulatorUIFigure);
            app.inxSlider.Limits = [-30 20];
            app.inxSlider.MajorTicks = [-50 -30 -10 0 10 20];
            app.inxSlider.ValueChangedFcn = createCallbackFcn(app, @inxSliderValueChanged, true);
            app.inxSlider.ValueChangingFcn = createCallbackFcn(app, @inxSliderValueChanging, true);
            app.inxSlider.MinorTicks = [-30 -26 -22 -18 -14 -10 -6 -2 0 2 6 10 14 18 20];
            app.inySliderLabel = uilabel(app.SphericalParallelManipulatorUIFigure);
            app.inySliderLabel.Text = 'iny';
            app.inySlider = uislider(app.SphericalParallelManipulatorUIFigure);
            app.inySlider.Limits = [-30 30];
            app.inySlider.MajorTicks = [-30 -10 0 10 30];
            app.inySlider.MajorTickLabels = {'-30', '-10', '0', '10', '30'};
            app.inySlider.ValueChangedFcn = createCallbackFcn(app, @inxSliderValueChanged, true);
            app.inySlider.ValueChangingFcn = createCallbackFcn(app, @inxSliderValueChanging, true);
            app.inySlider.MinorTicks = [-30 -26 -22 -18 -14 -10 -6 -2 0 2 6 10 14 18 22 26 30];
            app.inzSliderLabel = uilabel(app.SphericalParallelManipulatorUIFigure);
            app.inzSliderLabel.Text = 'inz';
            app.inzSlider = uislider(app.SphericalParallelManipulatorUIFigure);
            app.inzSlider.Limits = [-50 50];
            app.inzSlider.MajorTicks = [-50 -30 -10 0 10 30 50];
            app.inzSlider.MajorTickLabels = {'-50', '-30', '-10', '0', '10', '30', '50'};
            app.inzSlider.ValueChangedFcn = createCallbackFcn(app, @inxSliderValueChanged, true);
            app.inzSlider.ValueChangingFcn = createCallbackFcn(app, @inxSliderValueChanging, true);
            app.inzSlider.MinorTicks = [-50 -46 -42 -38 -34 -30 -26 -22 -18 -14 -10 -6 -2 0 2 6 10 14 18 22 26 30 34 38 42 46 50];
            app.Image2 = uiimage(app.SphericalParallelManipulatorUIFigure);
            app.Image2.ImageSource = 'SPM.jpg';
            app.TextArea = uitextarea(app.SphericalParallelManipulatorUIFigure);
            app.TextArea.Value = {'Syrian Arab Republic'; 'Higher Institute For Applied Sciences And Technology'; 'Department of Electromechanical Systems'};
            app.TextArea_2 = uitextarea(app.SphericalParallelManipulatorUIFigure);
            app.TextArea_2.Value = {'Zein Al Abideen Zreik'; 'Eng. Ali Al Abbas'; 'Eng. Nizar Falion'};
            app.MotorDegreesTextAreaLabel = uilabel(app.SphericalParallelManipulatorUIFigure);
            app.MotorDegreesTextAreaLabel.Text = 'Motor Degrees';
            app.MotorDegreesTextArea = uitextarea(app.SphericalParallelManipulatorUIFigure);
            app.MotorDegreesTextArea.Editable = 'off';
            app.TorqueTransmissionTextAreaLabel = uilabel(app.SphericalParallelManipulatorUIFigure);
            app.TorqueTransmissionTextAreaLabel.Text = 'Torque Transmission';
            app.TorqueTransmissionTextArea = uitextarea(app.SphericalParallelManipulatorUIFigure);
            app.TorqueTransmissionTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.SphericalParallelManipulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InverseKinematicsTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SphericalParallelManipulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SphericalParallelManipulatorUIFigure)
        end
    end
end